classdef phyHelpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin              matlab.ui.Figure
        rateMap              matlab.ui.control.UIAxes
        cluIDEditFieldLabel  matlab.ui.control.Label
        cluID                matlab.ui.control.NumericEditField
        Previous             matlab.ui.control.Button
        Next                 matlab.ui.control.Button
        dirMap               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataObj)

            app.mainWin.UserData.dataObj = dataObj;
            
            % sort clusters numerically to make browsing easier
            [app.mainWin.UserData.dataObj.cell_ID, sortInd]  = sortrows(app.mainWin.UserData.dataObj.cell_ID,1,'ascend');
            app.mainWin.UserData.dataObj.cell_ID = app.mainWin.UserData.dataObj.cell_ID - 1; % phy is 0-based index
            app.mainWin.UserData.dataObj.spikeData.spk_Times{1} = app.mainWin.UserData.dataObj.spikeData.spk_Times{1}(sortInd);
            if ~isempty(app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1})
                app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1} = app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}(sortInd);
            end
            f = fieldnames(app.mainWin.UserData.dataObj.maps);
            for i = 1:length(f)
                if ~isempty(app.mainWin.UserData.dataObj.maps.(f{i}){1}) && ~(strcmp(f{i},'pos') && length(app.mainWin.UserData.dataObj.maps.(f{i}){1}) == 1)
                    app.mainWin.UserData.dataObj.maps.(f{i}) = app.mainWin.UserData.dataObj.maps.(f{i}){1}(sortInd);
                end
            end
            
            % check if data is loaded and maps are made
            
            figure(app.mainWin);
        end

        % Value changed function: cluID
        function cluIDValueChanged(app, event)
        
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
            app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(cellInd,1);

            if ~any(cellInd)
                warning(['PlotClust: ' num2str(value) ' is not a valid cluster ID!']);
                return;
            end
            
            scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj.maps.rate{cellInd},app.rateMap,'colmap','jet','nsteps',11);
            axis(app.rateMap,'square');
            mapSz = size(app.mainWin.UserData.dataObj.maps.rate{cellInd});
            set(app.rateMap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.rateMap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj.maps.rate{cellInd})),'%.1f')],'Visible','On');
            
            scanpix.plot.plotDirMap(app.mainWin.UserData.dataObj.maps.dir{cellInd},app.dirMap);
            
            figure(app.mainWin);
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
        
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
        
            if cellInd ~= size(app.mainWin.UserData.dataObj.cell_ID,1)
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(cellInd+1,1);
            else
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(1,1);
            end
            hgfeval({@cluIDValueChanged,app,event});
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
        
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
        
            if cellInd ~= 1
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(cellInd-1,1);
            else
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(end,1);
            end
            hgfeval({@cluIDValueChanged,app,event});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'UI Figure';
            app.rateMap = uiaxes(app.mainWin);
            title(app.rateMap, '')
            xlabel(app.rateMap, '')
            ylabel(app.rateMap, '')
            app.rateMap.XTick = [];
            app.rateMap.YTick = [];
            app.cluIDEditFieldLabel = uilabel(app.mainWin);
            app.cluIDEditFieldLabel.Text = 'clu ID';
            app.cluID = uieditfield(app.mainWin, 'numeric');
            app.cluID.ValueChangedFcn = createCallbackFcn(app, @cluIDValueChanged, true);
            app.Previous = uibutton(app.mainWin, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Text = '<';
            app.Next = uibutton(app.mainWin, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Text = '>';
            app.dirMap = uiaxes(app.mainWin);
            title(app.dirMap, '')
            xlabel(app.dirMap, '')
            ylabel(app.dirMap, '')
            app.dirMap.XTick = [];
            app.dirMap.YTick = [];
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phyHelpGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end